classdef VideoFeatureViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoFeatureViewerUIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadvideoframesMenu         matlab.ui.container.Menu
        LoadfeaturespikesMenu       matlab.ui.container.Menu
        ExportMovieMenu             matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        MotionfeaturesPanel         matlab.ui.container.Panel
        StaticfeaturesPanel         matlab.ui.container.Panel
        MaxFrameEditField           matlab.ui.control.NumericEditField
        ofEditFieldLabel            matlab.ui.control.Label
        FrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel         matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        movie_ax                    matlab.ui.control.UIAxes
        motion_ax                   matlab.ui.control.UIAxes
        static_ax                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        curFrame = 1;
        frames_static = [];
        frames_diff = [];
        v; %video reader object
        video_params; %parameters for video
        feature_params; %parameter structure
        feature_spikes;
        feature_names = {}; %cell array of feature names
        feature_names_static = {};
        feature_names_motion = {};
        static_axes;
        motion_axes;
    end

    methods (Access = private)

        function updateDisplay(app)
            vid_frame = mean(read(app.v, app.curFrame),3);
            set(app.FrameEditField,'value',app.curFrame);
            image(app.movie_ax, vid_frame);
            image(app.static_ax, app.frames_static(:,:,app.curFrame));
            imagesc(app.motion_ax, app.frames_diff(:,:,app.curFrame));

            N_static = length(app.feature_names_static);
            N_motion = length(app.feature_names_motion);

            for i=1:N_static
                curFeature = app.feature_names_static{i};
                imagesc(app.static_axes.(['ax_', curFeature]), app.feature_spikes.(curFeature)(:,:,app.curFrame));
            end
            
            for i=1:N_motion
                curFeature = app.feature_names_motion{i};
                imagesc(app.motion_axes.(['ax_', curFeature]), app.feature_spikes.(curFeature)(:,:,app.curFrame));
            end

        end

        function initDisplay(app)
            colormap(app.movie_ax,'gray');
            %             xlim(app.movie_ax,[1, app.v.Width]);
            %             ylim(app.movie_ax,[1, app.v.Height]);
            axis(app.movie_ax,'tight');

            colormap(app.static_ax,'gray');
            axis(app.static_ax,'tight');

            colormap(app.motion_ax,'gray');
            axis(app.motion_ax,'tight');
            caxis(app.motion_ax,[-128 128])
            %TODO - put on axis (degrees)
            
            %remove old axes
            ch = get(app.StaticfeaturesPanel,'children');
            for i=1:length(ch)
                delete(ch(i));
            end
            ch = get(app.MotionfeaturesPanel,'children');
            for i=1:length(ch)
                delete(ch(i));
            end         
            
            N_static = length(app.feature_names_static);
            N_motion = length(app.feature_names_motion);

            %make featureSpikes sections
            border = .01;
            top_border = .1;
            gutter = .05;
            if N_static > 0
                spacing_static = 1 / N_static;
                L_edges = border:spacing_static:1;
                for i=1:N_static
                    curFeature = app.feature_names_static{i}
                    pos = [L_edges(i), border, spacing_static - gutter, 1 - top_border - border]
                    ax_name = ['ax_' curFeature];
                    static_axes.(ax_name) = uiaxes('parent',app.StaticfeaturesPanel, ...
                        'XTick',[], ...
                        'Ytick',[], ...
                        'Units','normalized', ...
                        'Position',pos)
                    colormap(static_axes.(ax_name), 'gray')
                    title(static_axes.(ax_name),curFeature,'Interpreter','none');          
                    axis(static_axes.(ax_name),'tight');
                    caxis(static_axes.(ax_name),[0 1]);
                end
                app.static_axes = static_axes;
            end
            if N_motion > 0
                spacing_motion = 1 / N_motion;
                L_edges = border:spacing_motion:1;
                for i=1:N_motion
                    curFeature = app.feature_names_motion{i}
                    pos = [L_edges(i), border, spacing_motion - gutter, 1 - top_border - border]
                    ax_name = ['ax_' curFeature];
                    motion_axes.(ax_name) = uiaxes('parent',app.MotionfeaturesPanel, ...
                        'XTick',[], ...
                        'Ytick',[], ...
                        'Units','normalized', ...
                        'Position',pos);
                    colormap(motion_axes.(ax_name), 'gray')
                    title(motion_axes.(ax_name),curFeature,'Interpreter','none');
                    axis(motion_axes.(ax_name),'tight');
                    caxis(motion_axes.(ax_name),[0 1]);
                end
                app.motion_axes = motion_axes;
            end

        end

        function initFrameSlider(app)
            nticks = 10;
            ticks = round(linspace(1, app.v.NumFrames, nticks));
            %ticks = 1:1:app.v.NumFrames;
            set(app.FrameSlider,'limits',[1 app.v.NumFrames]);
            set(app.FrameSlider,'value',1);
            set(app.FrameSlider,'MajorTicks',ticks);
            %set(app.FrameSlider,'MajorTickLabels',{});
            set(app.FrameEditField,'value',1);
            set(app.MaxFrameEditField,'value',app.v.NumFrames);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadvideoframesMenu
        function LoadvideoframesMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.mat', 'Open stored video frames file.');
            fname_full = [fpath fname];
            hash_name = strtok(fname, '_');
            if exist(fname_full,'file')
                try
                    load(fname_full,'frames_static', 'frames_diff');
                    load(sprintf('%s%s_vidParams.mat', fpath, hash_name), 'video_params');
                    app.video_params = video_params;
                    app.frames_static = frames_static;
                    app.frames_diff = frames_diff;
                    app.v = VideoReader(video_params.video_fname);
                catch ME
                    fprintf('Error loading files for %s\n', hash_name);
                    rethrow(ME);
                end
                app.initDisplay();
                initFrameSlider(app)
                app.updateDisplay()
            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.curFrame = round(app.FrameSlider.Value);
            app.updateDisplay();
        end

        % Key press function: VideoFeatureViewerUIFigure
        function VideoFeatureViewerUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    if app.curFrame>1
                        app.curFrame = app.curFrame-1;
                        set(app.FrameSlider,'value',app.curFrame);
                        app.updateDisplay();
                    end
                case 'rightarrow'
                    if app.curFrame<app.v.NumFrames
                        app.curFrame = app.curFrame+1;
                        set(app.FrameSlider,'value',app.curFrame);
                        app.updateDisplay();
                    end
            end
            %fprintf('Key: %s\n', key);
        end

        % Close request function: VideoFeatureViewerUIFigure
        function VideoFeatureViewerUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.VideoFeatureViewerUIFigureCloseRequest(event);
        end

        % Menu selected function: LoadfeaturespikesMenu
        function LoadfeaturespikesMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.mat', 'Open stored feature spikes file.');
            fname_full = [fpath fname];
            hash_name = strtok(fname, '_');
            if exist(fname_full,'file')
                try
                    load(fname_full,'feature_spikes');
                    load(sprintf('%s%s_params.mat', fpath, hash_name), 'params');
                    app.feature_params = params.features;
                    app.feature_spikes = feature_spikes;
                    app.feature_names = fieldnames(feature_spikes);
                    static_features = fieldnames(params.features.features_static);
                    motion_features = fieldnames(params.features.features_motion);
                    app.feature_names_static = intersect(app.feature_names,static_features);
                    app.feature_names_motion = intersect(app.feature_names,motion_features);
                catch ME
                    fprintf('Error loading files for %s\n', hash_name);
                    rethrow(ME);
                end
                app.initDisplay();
                app.updateDisplay()
            end
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value
            if value > 0 && value<=app.v.NumFrames
                app.curFrame = value;
                set(app.FrameSlider,'value',app.curFrame);
                app.updateDisplay();
            end
        end

        % Menu selected function: ExportMovieMenu
        function ExportMovieMenuSelected(app, event)
            [fname,fpath] = uiputfile('*.avi','Save video as');
            if ~isempty(fname)
                v = VideoWriter([fpath fname]);
                open(v);
            else
                return
            end
            
            %tempN = 200;

            for i=1:app.v.NumFrames
            %for i=1:tempN
                i
                set(app.FrameSlider,'value',i);
                app.curFrame = i;
                app.updateDisplay();
                drawnow;
                F = getframe(app.VideoFeatureViewerUIFigure);   
                writeVideo(v, F);
            end
            
            close(v);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoFeatureViewerUIFigure = uifigure('Visible', 'off');
            app.VideoFeatureViewerUIFigure.Name = 'VideoFeatureViewer';
            app.VideoFeatureViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @VideoFeatureViewerUIFigureCloseRequest, true);
            app.VideoFeatureViewerUIFigure.KeyPressFcn = createCallbackFcn(app, @VideoFeatureViewerUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.VideoFeatureViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadvideoframesMenu = uimenu(app.FileMenu);
            app.LoadvideoframesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadvideoframesMenuSelected, true);
            app.LoadvideoframesMenu.Text = 'Load video frames';
            app.LoadfeaturespikesMenu = uimenu(app.FileMenu);
            app.LoadfeaturespikesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfeaturespikesMenuSelected, true);
            app.LoadfeaturespikesMenu.Text = 'Load feature spikes';
            app.ExportMovieMenu = uimenu(app.FileMenu);
            app.ExportMovieMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMovieMenuSelected, true);
            app.ExportMovieMenu.Text = 'ExportMovie';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.static_ax = uiaxes(app.VideoFeatureViewerUIFigure);
            title(app.static_ax, 'Static feature pixels')
            zlabel(app.static_ax, 'Z')
            app.static_ax.XTick = [];
            app.static_ax.YTick = [];
            app.motion_ax = uiaxes(app.VideoFeatureViewerUIFigure);
            title(app.motion_ax, 'Motion feature pixels')
            zlabel(app.motion_ax, 'Z')
            app.motion_ax.XTick = [];
            app.motion_ax.YTick = [];
            app.movie_ax = uiaxes(app.VideoFeatureViewerUIFigure);
            title(app.movie_ax, 'Original movie')
            zlabel(app.movie_ax, 'Z')
            app.movie_ax.XTick = [];
            app.movie_ax.YTick = [];
            app.FrameSlider = uislider(app.VideoFeatureViewerUIFigure);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameEditFieldLabel = uilabel(app.VideoFeatureViewerUIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.VideoFeatureViewerUIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.ofEditFieldLabel = uilabel(app.VideoFeatureViewerUIFigure);
            app.ofEditFieldLabel.Text = 'of';
            app.MaxFrameEditField = uieditfield(app.VideoFeatureViewerUIFigure, 'numeric');
            app.MaxFrameEditField.Editable = 'off';
            app.StaticfeaturesPanel = uipanel(app.VideoFeatureViewerUIFigure);
            app.StaticfeaturesPanel.Title = 'Static features';
            app.MotionfeaturesPanel = uipanel(app.VideoFeatureViewerUIFigure);
            app.MotionfeaturesPanel.Title = 'Motion features';
            % Show the figure after all components are created
            app.VideoFeatureViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoFeatureViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideoFeatureViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoFeatureViewerUIFigure)
        end
    end
end